classdef Lane_Ecg_Monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GenerateReportButton      matlab.ui.control.Button
        AIECGPATIENTMONITORLabel  matlab.ui.control.Label
        LANEHEALTHCAREINCLabel    matlab.ui.control.Label
        ExitButton                matlab.ui.control.Button
        SaveReportButton          matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        PatientInformationPanel   matlab.ui.container.Panel
        GenderFieldLabel          matlab.ui.control.Label
        AgeFieldLabel             matlab.ui.control.Label
        PatientNameField          matlab.ui.control.Label
        PatientIDField            matlab.ui.control.Label
        GenderLabel               matlab.ui.control.Label
        AgeLabel                  matlab.ui.control.Label
        NameLabel                 matlab.ui.control.Label
        PatientIDLabel            matlab.ui.control.Label
        SignalWaitingLabel        matlab.ui.control.Label
        SignalQualityLabel        matlab.ui.control.Label
        SystemReadyLabel          matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        WaitingLabel_2            matlab.ui.control.Label
        WaitingLabel              matlab.ui.control.Label
        BPMLabel                  matlab.ui.control.Label
        AlarmLabel                matlab.ui.control.Label
        AccuracyLabel             matlab.ui.control.Label
        StatusValueLabel          matlab.ui.control.Label
        RhythmValueLabel          matlab.ui.control.Label
        HeartRateLabel            matlab.ui.control.Label
        ECGAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      ECGData
      CurrentIndex = 1
      WindowSize = 500
      ECGTimer
    end


    methods (Access = private)
        
       function updateECG(app)
           if isempty(app.ECGData)
    return
end
    startIndex = app.CurrentIndex;

endIndex = startIndex + app.WindowSize - 1;

    if endIndex > length(app.ECGData)

    app.CurrentIndex = 1;

    return

end

    segment = app.ECGData(startIndex:endIndex);   

    plot(app.ECGAxes, segment, 'g', 'LineWidth', 1.5);

    app.CurrentIndex = app.CurrentIndex + 10;

    grid(app.ECGAxes,'on');
     
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)


 % Create a sample ECG-like signal

data = readmatrix('ecg.csv');

ecg_signal = data(:,1);

% Filtering
Fs = 360;   % Sampling frequency

[b,a] = butter(4,[5 40]/(Fs/2),'bandpass');

% Remove 50 Hz electrical interference
wo = 50/(Fs/2);
bw = wo/35;

[bn,an] = iirnotch(wo,bw);

filtered_ecg = filtfilt(b,a,ecg_signal);
app.ECGData = filtered_ecg;
app.CurrentIndex = 1;

app.ECGData = filtered_ecg;


threshold = 0.35 * max(filtered_ecg);

[pks,locs] = findpeaks(filtered_ecg,'MinPeakDistance',round(0.6*Fs),'MinPeakHeight',threshold);

 time_duration = length(filtered_ecg)/Fs;

heart_rate = length(locs)/time_duration*60;

% Estimate detection accuracy

RR_intervals = diff(locs)/Fs;

if length(RR_intervals) > 2

    RR_variation = std(RR_intervals);

    % Signal quality estimation

peak_consistency = 1 - (std(diff(locs))/mean(diff(locs)));

peak_consistency = max(min(peak_consistency,1),0);

accuracy = peak_consistency;

else

    accuracy = 0.5;

end

if accuracy >= 0.9

    accuracy_status = "Excellent Signal";

elseif accuracy >= 0.7

    accuracy_status = "Good Signal";

else

    accuracy_status = "Poor Signal";

end

app.Label_9.Text = sprintf('%.1f %%',accuracy*100);

if accuracy >= 0.8

    app.Label_9.FontColor = [0 0.6 0];
    
elseif accuracy >= 0.6

    app.Label_9.FontColor = [1 0.6 0];
    
else

    app.Label_9.FontColor = [1 0 0];

end

app.SignalWaitingLabel.Text = accuracy_status;


if heart_rate >= 60 && heart_rate <= 100

    rhythm_status = "Normal Rhythm";
    status_message = "Patient Stable";

elseif heart_rate > 100

    rhythm_status = "Tachycardia";
    status_message = "High Heart Rate";

elseif heart_rate < 60

    rhythm_status = "Bradycardia";
    status_message = "Low Heart Rate";

end


app.WaitingLabel.Text = rhythm_status;

app.WaitingLabel_2.Text = status_message;

app.BPMLabel.Text = sprintf('%.1f BPM',heart_rate);
 
% Alarm

if heart_rate >= 60 && heart_rate <= 100

    alarm_message = "SYSTEM NORMAL";

elseif heart_rate > 100

    alarm_message = "⚠ HIGH HEART RATE";

elseif heart_rate < 60

    alarm_message = "⚠ LOW HEART RATE";

end
app.SystemReadyLabel.Text = alarm_message;

% Plot on the app axes

plot(app.ECGAxes,filtered_ecg,'g','LineWidth',1.5);

hold(app.ECGAxes,'on');

plot(app.ECGAxes,locs,pks,'ro','MarkerSize',8,'LineWidth',2);

hold(app.ECGAxes,'off');

grid(app.ECGAxes,'on');

title(app.ECGAxes,'ECG Signal');

for k = 1:100

    updateECG(app);

    pause(0.05);

    drawnow;
    

end

        end

        % Size changed function: PatientInformationPanel
        function PatientInformationPanelSizeChanged(app, event)
            position = app.PatientInformationPanel.Position;
            
        end

        % Button pushed function: GenerateReportButton
        function ButtonPushedFcn(app, event)
 % Generate ECG Report

import mlreportgen.dom.*

filename = 'ECG_Report.pdf';

% Save ECG plot image

ecgImage = 'ECG_plot.png';

exportgraphics(app.ECGAxes,ecgImage);

doc = Document(filename,'pdf');


% Title

title = Paragraph('Lane ECG Monitor Report');
title.Bold = true;
title.FontSize = '18pt';

append(doc,title);


% Date and time

append(doc,Paragraph(['Generated: ', datestr(now)]));


% Patient information

append(doc,Paragraph('--------------------------------'));

append(doc,Paragraph(['PatientID: ', app.PatientIDField.Value]));

append(doc,Paragraph(['Name: ', app.PatientNameField.Value]));

append(doc,Paragraph(['Age: ', app.AgeFieldLabel.Value]));

append(doc,Paragraph(['Gender: ', app.GenderFieldLabel.Value]));


append(doc,Paragraph(['Heart Rate: ', app.BPMLabel.Text]));


append(doc,Paragraph(['Rhythm: ', app.WaitingLabel.Text]));


append(doc,Paragraph(['Status: ', app.WaitingLabel_2.Text]));


append(doc,Paragraph(['Accuracy: ', app.Label_9.Text]));


append(doc,Paragraph(['Signal Quality: ', app.SignalWaitingLabel.Text]));

% Add ECG waveform image

append(doc,Paragraph('ECG Waveform:'));

img = Image(ecgImage);

img.Width = '6in';

img.Height = '3in';

append(doc,img);


% Close document

close(doc);


msgbox('ECG Report Created Successfully');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ECGAxes = uiaxes(app.UIFigure);
            title(app.ECGAxes, 'ECG Waveform')
            xlabel(app.ECGAxes, 'Samples')
            ylabel(app.ECGAxes, 'Amplitude')
            zlabel(app.ECGAxes, 'Z')
            app.PatientInformationPanel = uipanel(app.UIFigure);
            app.PatientInformationPanel.Title = 'Patient Information';
            app.PatientInformationPanel.SizeChangedFcn = createCallbackFcn(app, @PatientInformationPanelSizeChanged, true);
            app.HeartRateLabel = uilabel(app.PatientInformationPanel);
            app.HeartRateLabel.Text = 'Heart Rate: ';
            app.RhythmValueLabel = uilabel(app.PatientInformationPanel);
            app.RhythmValueLabel.Text = 'Rhythm:';
            app.StatusValueLabel = uilabel(app.PatientInformationPanel);
            app.StatusValueLabel.Text = 'Status:';
            app.AccuracyLabel = uilabel(app.PatientInformationPanel);
            app.AccuracyLabel.Text = 'Accuracy:';
            app.AlarmLabel = uilabel(app.PatientInformationPanel);
            app.AlarmLabel.Text = 'Alarm:';
            app.BPMLabel = uilabel(app.PatientInformationPanel);
            app.BPMLabel.Text = '0 BPM';
            app.WaitingLabel = uilabel(app.PatientInformationPanel);
            app.WaitingLabel.Text = 'Waiting...';
            app.WaitingLabel_2 = uilabel(app.PatientInformationPanel);
            app.WaitingLabel_2.Text = 'Waiting...';
            app.Label_9 = uilabel(app.PatientInformationPanel);
            app.Label_9.Text = '0%';
            app.SystemReadyLabel = uilabel(app.PatientInformationPanel);
            app.SystemReadyLabel.Text = 'System Ready';
            app.SignalQualityLabel = uilabel(app.PatientInformationPanel);
            app.SignalQualityLabel.Text = 'Signal Quality:';
            app.SignalWaitingLabel = uilabel(app.PatientInformationPanel);
            app.SignalWaitingLabel.Text = 'Waiting...';
            app.PatientIDLabel = uilabel(app.PatientInformationPanel);
            app.PatientIDLabel.Text = 'Patient ID:';
            app.NameLabel = uilabel(app.PatientInformationPanel);
            app.NameLabel.Text = 'Name:';
            app.AgeLabel = uilabel(app.PatientInformationPanel);
            app.AgeLabel.Text = 'Age:';
            app.GenderLabel = uilabel(app.PatientInformationPanel);
            app.GenderLabel.Text = 'Gender:';
            app.PatientIDField = uilabel(app.PatientInformationPanel);
            app.PatientIDField.Text = 'PatientIDField';
            app.PatientNameField = uilabel(app.PatientInformationPanel);
            app.PatientNameField.Text = 'PatientNameField';
            app.AgeFieldLabel = uilabel(app.PatientInformationPanel);
            app.AgeFieldLabel.Text = 'AgeField';
            app.GenderFieldLabel = uilabel(app.PatientInformationPanel);
            app.GenderFieldLabel.Text = 'GenderField';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.SaveReportButton = uibutton(app.UIFigure, 'push');
            app.SaveReportButton.Text = 'Save Report';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            app.LANEHEALTHCAREINCLabel = uilabel(app.UIFigure);
            app.LANEHEALTHCAREINCLabel.Text = 'LANE HEALTHCARE INC';
            app.AIECGPATIENTMONITORLabel = uilabel(app.UIFigure);
            app.AIECGPATIENTMONITORLabel.Text = 'AI ECG PATIENT MONITOR';
            app.GenerateReportButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushedFcn, true);
            app.GenerateReportButton.Text = 'Generate Report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lane_Ecg_Monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end